classdef DemoTools < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DemoToolsUIFigure  matlab.ui.Figure
        FontSizer          matlab.ui.control.Button
        ZoomIt             matlab.ui.control.Button
        FFav               matlab.ui.control.Button
        ZFav               matlab.ui.control.Button
        Cleaner            matlab.ui.control.Button
        CFav               matlab.ui.control.Button
        RT                 matlab.ui.control.Button
        RTFav              matlab.ui.control.Button
        AllFav             matlab.ui.control.Button
        Organizer          matlab.ui.control.Button
        OFav               matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function addFav(~, label, icon, code)
            fc = com.mathworks.mlwidgets.favoritecommands.FavoriteCommands.getInstance();
            newFav = com.mathworks.mlwidgets.favoritecommands.FavoriteCommandProperties();
            newFav.setLabel(label);
            newFav.setCategoryLabel("DEMO TOOLS");
            p = fileparts(mfilename('fullpath'));
            newFav.setIconPath(p);
            newFav.setIconName(icon);
            newFav.setCode(code);
            newFav.setIsOnQuickToolBar(true);
            fc.addCommand(newFav);
        end
    end
    

    methods (Access = private)

        % Button pushed function: ZoomIt
        function ZoomItButtonPushed(app, event)
            ZoomIt;
        end

        % Button pushed function: FontSizer
        function FontSizerButtonPushed(app, event)
            FontSizer;
        end

        % Button pushed function: FFav
        function FFavButtonPushed(app, event)
            app.addFav("Change font size", "fontsizer-icon.png", "FontSizer");
        end

        % Button pushed function: ZFav
        function ZFavButtonPushed(app, event)
            app.addFav("ZoomIt", "zoomit-icon.png", "ZoomIt");
        end

        % Button pushed function: CFav
        function CFavButtonPushed(app, event)
            app.addFav("Clear", "cleaner-icon.png", "Cleaner");
        end

        % Button pushed function: RTFav
        function RTFavButtonPushed(app, event)
            app.addFav("SLRT Explorer", "slrt-icon.png", "slrtexplr");
        end

        % Button pushed function: RT
        function RTButtonPushed(app, event)
            slrtexplr
        end

        % Button pushed function: Cleaner
        function CleanerButtonPushed(app, event)
            Cleaner
        end

        % Button pushed function: AllFav
        function AllFavButtonPushed(app, event)
            app.RTFavButtonPushed();
            app.ZFavButtonPushed();
            app.FFavButtonPushed();
            app.OFavButtonPushed();
            app.CFavButtonPushed();
        end

        % Button pushed function: Organizer
        function OrganizerButtonPushed(app, event)
            Organizer
        end

        % Button pushed function: OFav
        function OFavButtonPushed(app, event)
            app.addFav("Projects organizer", "organizer-icon.png", "Organizer");
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DemoToolsUIFigure = uifigure;
            app.DemoToolsUIFigure.Name = 'Demo Tools';
            app.FontSizer = uibutton(app.DemoToolsUIFigure, 'push');
            app.FontSizer.ButtonPushedFcn = createCallbackFcn(app, @FontSizerButtonPushed, true);
            app.FontSizer.Icon = 'fontsizer-icon.png';
            app.FontSizer.Text = 'FontSizer';
            app.ZoomIt = uibutton(app.DemoToolsUIFigure, 'push');
            app.ZoomIt.ButtonPushedFcn = createCallbackFcn(app, @ZoomItButtonPushed, true);
            app.ZoomIt.Icon = 'zoomit-icon.png';
            app.ZoomIt.Text = 'ZoomIt';
            app.FFav = uibutton(app.DemoToolsUIFigure, 'push');
            app.FFav.ButtonPushedFcn = createCallbackFcn(app, @FFavButtonPushed, true);
            app.FFav.Icon = 'favorites.png';
            app.FFav.Tooltip = {'Add to Favorites'};
            app.FFav.Text = '';
            app.ZFav = uibutton(app.DemoToolsUIFigure, 'push');
            app.ZFav.ButtonPushedFcn = createCallbackFcn(app, @ZFavButtonPushed, true);
            app.ZFav.Icon = 'favorites.png';
            app.ZFav.Tooltip = {'Add to Favorites'};
            app.ZFav.Text = '';
            app.Cleaner = uibutton(app.DemoToolsUIFigure, 'push');
            app.Cleaner.ButtonPushedFcn = createCallbackFcn(app, @CleanerButtonPushed, true);
            app.Cleaner.Icon = 'cleaner-icon.png';
            app.Cleaner.Text = 'Cleaner';
            app.CFav = uibutton(app.DemoToolsUIFigure, 'push');
            app.CFav.ButtonPushedFcn = createCallbackFcn(app, @CFavButtonPushed, true);
            app.CFav.Icon = 'favorites.png';
            app.CFav.Tooltip = {'Add to Favorites'};
            app.CFav.Text = '';
            app.RT = uibutton(app.DemoToolsUIFigure, 'push');
            app.RT.ButtonPushedFcn = createCallbackFcn(app, @RTButtonPushed, true);
            app.RT.Icon = 'slrt-icon.png';
            app.RT.Text = 'slrtexplr';
            app.RTFav = uibutton(app.DemoToolsUIFigure, 'push');
            app.RTFav.ButtonPushedFcn = createCallbackFcn(app, @RTFavButtonPushed, true);
            app.RTFav.Icon = 'favorites.png';
            app.RTFav.Tooltip = {'Add to Favorites'};
            app.RTFav.Text = '';
            app.AllFav = uibutton(app.DemoToolsUIFigure, 'push');
            app.AllFav.ButtonPushedFcn = createCallbackFcn(app, @AllFavButtonPushed, true);
            app.AllFav.Icon = 'favorites.png';
            app.AllFav.Tooltip = {'Add to Favorites'};
            app.AllFav.Text = 'All';
            app.Organizer = uibutton(app.DemoToolsUIFigure, 'push');
            app.Organizer.ButtonPushedFcn = createCallbackFcn(app, @OrganizerButtonPushed, true);
            app.Organizer.Icon = 'Organizer-icon.png';
            app.Organizer.Text = 'Organizer';
            app.OFav = uibutton(app.DemoToolsUIFigure, 'push');
            app.OFav.ButtonPushedFcn = createCallbackFcn(app, @OFavButtonPushed, true);
            app.OFav.Icon = 'favorites.png';
            app.OFav.Tooltip = {'Add to Favorites'};
            app.OFav.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DemoTools

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DemoToolsUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DemoToolsUIFigure)
        end
    end
end